classdef Fuzzy_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FuzzyexpertsystemforcustomerprofilingLabel  matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        MailingRatingTextArea           matlab.ui.control.TextArea
        MailingRatingTextAreaLabel      matlab.ui.control.Label
        RealEstateMortgageEditField     matlab.ui.control.NumericEditField
        RealEstateMortgageEditFieldLabel  matlab.ui.control.Label
        FixedExpensesEditField          matlab.ui.control.NumericEditField
        FixedExpensesEditFieldLabel     matlab.ui.control.Label
        NumberOfChildrenEditField       matlab.ui.control.NumericEditField
        NumberOfChildrenEditFieldLabel  matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
        LocationButtonGroup             matlab.ui.container.ButtonGroup
        RuralButton                     matlab.ui.control.RadioButton
        SuburbButton                    matlab.ui.control.RadioButton
        CityButton                      matlab.ui.control.RadioButton
        CheckIfMarriedCheckBox          matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Update Varibles
            evalin('base', sprintf('Real_Estate_Mortgage = %f;', app.RealEstateMortgageEditField.Value));
            evalin('base', sprintf('Fixed_Expenses = %f;', app.FixedExpensesEditField.Value));

            evalin('base', sprintf('Age = %f;', app.AgeEditField.Value));
            evalin('base', sprintf('Num_Children = %f;', app.NumberOfChildrenEditField.Value));
            if (app.CheckIfMarriedCheckBox.Value)
                evalin('base', sprintf('Marital_Status = %f;', 1));
            else
                evalin('base', sprintf('Marital_Status = %f;', 0));
            end

            % Get social status
            switch app.LocationButtonGroup.SelectedObject.Text
                case 'City'
                    evalin('base', sprintf('Social = %f;', 1));
                case 'Suburb'
                    evalin('base', sprintf('Social = %f;', 0.5));
                case 'Rural'
                    evalin('base', sprintf('Social = %f;', 0.25));
            end

            % Run Fuzzy System
            sm = simulation("Fuzzy_System");
            start(sm);
            pause(sm);
            step(sm,PauseTime=1);
            outputData = stop(sm);

            % Show Output
            output_str = sprintf("%.2f/10", outputData.yout{1}.Values.Data(end)*10);
            app.MailingRatingTextArea.Value = output_str;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CheckIfMarriedCheckBox = uicheckbox(app.UIFigure);
            app.CheckIfMarriedCheckBox.Text = 'Check If Married';
            app.LocationButtonGroup = uibuttongroup(app.UIFigure);
            app.LocationButtonGroup.Title = 'Location';
            app.CityButton = uiradiobutton(app.LocationButtonGroup);
            app.CityButton.Text = 'City';
            app.CityButton.Value = true;
            app.SuburbButton = uiradiobutton(app.LocationButtonGroup);
            app.SuburbButton.Text = 'Suburb';
            app.RuralButton = uiradiobutton(app.LocationButtonGroup);
            app.RuralButton.Text = 'Rural';
            app.AgeEditFieldLabel = uilabel(app.UIFigure);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AgeEditField.Limits = [0 100];
            app.NumberOfChildrenEditFieldLabel = uilabel(app.UIFigure);
            app.NumberOfChildrenEditFieldLabel.Text = 'Number Of Children';
            app.NumberOfChildrenEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberOfChildrenEditField.Limits = [0 10];
            app.NumberOfChildrenEditField.RoundFractionalValues = 'on';
            app.FixedExpensesEditFieldLabel = uilabel(app.UIFigure);
            app.FixedExpensesEditFieldLabel.Text = 'Fixed Expenses';
            app.FixedExpensesEditField = uieditfield(app.UIFigure, 'numeric');
            app.FixedExpensesEditField.Limits = [0 100000];
            app.RealEstateMortgageEditFieldLabel = uilabel(app.UIFigure);
            app.RealEstateMortgageEditFieldLabel.Text = 'Real Estate Mortgage';
            app.RealEstateMortgageEditField = uieditfield(app.UIFigure, 'numeric');
            app.RealEstateMortgageEditField.Limits = [0 10000000];
            app.MailingRatingTextAreaLabel = uilabel(app.UIFigure);
            app.MailingRatingTextAreaLabel.Text = 'Mailing Rating';
            app.MailingRatingTextArea = uitextarea(app.UIFigure);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FuzzyexpertsystemforcustomerprofilingLabel = uilabel(app.UIFigure);
            app.FuzzyexpertsystemforcustomerprofilingLabel.Text = {'Fuzzy expert system '; 'for customer profiling'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fuzzy_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end